classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        enrollmentwindow                matlab.ui.container.Panel
        SubmitDataset_2                 matlab.ui.control.Button
        PathLabel                       matlab.ui.control.Label
        EnterNewDataSetLabel            matlab.ui.control.Label
        SubmitDataset                   matlab.ui.control.Button
        enrollment_EditField_label      matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NameLabel_2                     matlab.ui.control.Label
        enrollment_EditField_name       matlab.ui.control.EditField
        EditFieldLabel_2                matlab.ui.control.Label
        back6                           matlab.ui.control.Button
        scenarioHomeButton              matlab.ui.control.Button
        YoureSuccessfullyEnrolledLabel  matlab.ui.control.Label
        enrollment_label                matlab.ui.control.Label
        EnrollButton                    matlab.ui.control.Button
        enrollment_browse               matlab.ui.control.Button
        enrollment_image                matlab.ui.control.Image
        home                            matlab.ui.container.Panel
        EnterifyoureadminLabel          matlab.ui.control.Label
        clickButton                     matlab.ui.control.Button
        ScenarioEvaluationButton        matlab.ui.control.Button
        TechnologyEvaluationButton      matlab.ui.control.Button
        IRISDetectionProjectLabel       matlab.ui.control.Label
        credentialswindow               matlab.ui.container.Panel
        back5                           matlab.ui.control.Button
        loginpanel                      matlab.ui.container.Panel
        IncorrectPasswordLabel          matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        EnterCredentialsLabel           matlab.ui.control.Label
        decisiontreewindow              matlab.ui.container.Panel
        decisiontree_accuracy           matlab.ui.control.Label
        back_button                     matlab.ui.control.Button
        Label_name                      matlab.ui.control.Label
        decisiontree_submitButton       matlab.ui.control.Button
        decisiontree_model_image        matlab.ui.control.Image
        decisiontree_model_browse       matlab.ui.control.Button
        model_label_2                   matlab.ui.control.Label
        model_performance               matlab.ui.container.Panel
        back4                           matlab.ui.control.Button
        TMRatFMR001Label                matlab.ui.control.Label
        TMRatFMR1Label                  matlab.ui.control.Label
        Rank1Label                      matlab.ui.control.Label
        DPrimeLabel                     matlab.ui.control.Label
        EERLabel                        matlab.ui.control.Label
        EERCurveLabel                   matlab.ui.control.Label
        ROCCurveLabel                   matlab.ui.control.Label
        GenImpCurveLabel                matlab.ui.control.Label
        roc_image                       matlab.ui.control.Image
        eer_image                       matlab.ui.control.Image
        genimp_image                    matlab.ui.control.Image
        ModelAccuracyLabel              matlab.ui.control.Label
        technologyevaluation            matlab.ui.container.Panel
        back1                           matlab.ui.control.Button
        DecisionTreeButton              matlab.ui.control.Button
        ChooseAlgorithmLabel            matlab.ui.control.Label
        KNNButton                       matlab.ui.control.Button
        DeepLearningButton              matlab.ui.control.Button
        modelwindow                     matlab.ui.container.Panel
        modewindow_Label_2              matlab.ui.control.Label
        back3                           matlab.ui.control.Button
        modewindow_Label                matlab.ui.control.Label
        submitButton                    matlab.ui.control.Button
        viewperformanceButton           matlab.ui.control.Button
        model_image                     matlab.ui.control.Image
        model_browse                    matlab.ui.control.Button
        model_label                     matlab.ui.control.Label
        scenarioevaluation              matlab.ui.container.Panel
        NotastudentLabel                matlab.ui.control.Label
        ProbabilityLabel                matlab.ui.control.Label
        NameLabel                       matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        EnrollmentButton                matlab.ui.control.Button
        back2                           matlab.ui.control.Button
        scenario_Image                  matlab.ui.control.Image
        scenario_Browse                 matlab.ui.control.Button
        InsertYourPhotoLabel            matlab.ui.control.Label
        WelcometoAinShamsAttendanceSystemLabel  matlab.ui.control.Label
    end

properties (Access = private)
        choosen_model_button
        tech_evaluation_image

        name
        probability
        not

        enrollment_editfield_label
        enrollment_editfield_name
        enroll_image
        
        scenario_image    

        decisiontree_image
        usernamefield
        passwordfield

folderName
        accuracy
        EER_number
        D_prime 
        Rank_1
        TMR_at_FMR_1 
        TMR_at_FMR_001
            
        % images
        gen_imp
        roc
        EER_image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uistack(app.home,"top");
        end

        % Button pushed function: scenario_Browse
        function scenario_BrowsePushed(app, event)
            global scenario_image
            % Open file dialog for image selection
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select Image File');
    
    % Check if the user canceled file selection
    if isequal(file, 0)
        return; % User canceled, do nothing
    end
    
    % Construct full file path
    scenario_image = fullfile(path, file);
    
    
    % Display the image in the Image component
    app.scenario_Image.ImageSource = scenario_image;
    app.UIFigure.WindowStyle='normal';
    app.UIFigure.WindowState='normal';
        end

        % Button pushed function: TechnologyEvaluationButton
        function TechnologyEvaluationButtonPushed(app, event)
            uistack(app.technologyevaluation,"top");
        end

        % Button pushed function: ScenarioEvaluationButton
        function ScenarioEvaluationButtonPushed(app, event)

            uistack(app.scenarioevaluation,"top");
            app.NameLabel.Visible = "off";
            app.ProbabilityLabel.Visible ='off';
            app.NotastudentLabel.Visible='off';

            app.scenario_Image.ImageSource = "";

        end

        % Button pushed function: back1
        function back1Pushed(app, event)
            uistack(app.home,"top");
        end

        % Button pushed function: back2
        function back2ButtonPushed(app, event)
            uistack(app.home,"top");
        end

        % Button pushed function: DeepLearningButton
        function DeepLearningButtonPushed(app, event)


    addpath('D:\year3\biometrics\Project_final\performance\');
  
    addpath('D:\year3\biometrics\Project_final\scenario\');


            global  accuracy
        global EER_number
        global D_prime 
        global Rank_1
        global TMR_at_FMR_1 
        global TMR_at_FMR_001
            
        % images
        global gen_imp
        global roc
        global EER_image
            
            global choosen_model_button;
            choosen_model_button =  app.DeepLearningButton.Text;
             [acc1,genuine_scores,imposter_scores,GenImpImage,tpr,fpr,ROCImage,eer_Deep,eerImage,d_prime_Deep,tmr1_Deep,tmr001_Deep,rank1rate_Deep] = performance_measures_Deep()

            %numbers
            accuracy = acc1;
            EER_number = eer_Deep;
            D_prime = d_prime_Deep;
            Rank_1 = rank1rate_Deep;
            TMR_at_FMR_1 = tmr1_Deep;
            TMR_at_FMR_001 = tmr001_Deep;
            
            % images
            gen_imp = GenImpImage;
            roc = ROCImage;
            EER_image = eerImage;

            uistack(app.modelwindow,"top");

        end

        % Button pushed function: DecisionTreeButton
        function DecisionTreeButtonPushed(app, event)
            addpath('D:\year3\biometrics\Project_final\Feature_extraction\');
              addpath('D:\year3\biometrics\Project_final\matching\');
                addpath('D:\year3\biometrics\Project_final\models\');
                  addpath('D:\year3\biometrics\Project_final\performance\');
                    addpath('D:\year3\biometrics\Project_final\scenario\');
            global choosen_model_button; 
            global  accuracy
        global EER_number
        global D_prime 
        global Rank_1
        global TMR_at_FMR_1 
        global TMR_at_FMR_001
            
        % images
        global gen_imp
        global roc
        global EER_image

        app.decisiontree_accuracy.Text = "Accuracy :  ";
            choosen_model_button =  app.DecisionTreeButton.Text;
            accuracy = return_tree_accuracy();
            app.decisiontree_accuracy.Text = [app.decisiontree_accuracy.Text,num2str(accuracy)];
            uistack(app.decisiontreewindow,"top");

        end

        % Button pushed function: KNNButton
        function KNNButtonPushed(app, event)
                        addpath('D:\year3\biometrics\Project_final\Feature_extraction\');
              addpath('D:\year3\biometrics\Project_final\matching\');
                addpath('D:\year3\biometrics\Project_final\models\');
                  addpath('D:\year3\biometrics\Project_final\performance\');
                    addpath('D:\year3\biometrics\Project_final\scenario\');
          global choosen_model_button;
            global  accuracy
        global EER_number
        global D_prime 
        global Rank_1
        global TMR_at_FMR_1 
        global TMR_at_FMR_001
            
        % images
        global gen_imp
        global roc
        global EER_image
            choosen_model_button = app.KNNButton.Text;
            [acc1,genuine_scores,imposter_scores,GenImpImage,tpr,fpr,ROCImage,eer_KNN,eerImage,d_prime_KNN,tmr1_KNN,tmr001_KNN,rank1rate_KNN] = performance_measures_KNN();
            
            %numbers
            accuracy = acc1;
            EER_number = eer_KNN;
            D_prime = d_prime_KNN;
            Rank_1 = rank1rate_KNN;
            TMR_at_FMR_1 = tmr1_KNN;
            TMR_at_FMR_001 = tmr001_KNN;
            
            % images
            gen_imp = GenImpImage;
            roc = ROCImage;
            EER_image = eerImage;
            uistack(app.modelwindow,"top");

        end

        % Button pushed function: model_browse
        function model_browseButtonPushed(app, event)
            global tech_evaluation_image;
            % Open file dialog for image selection
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png,*.bmp'}, 'Select Image File');
    
    % Check if the user canceled file selection
    if isequal(file, 0)
        return; % User canceled, do nothing
    end
    
    % Construct full file path
    tech_evaluation_image = fullfile(path, file);
    
    
    % Display the image in the Image component
    app.model_image.ImageSource = tech_evaluation_image;
    app.UIFigure.WindowStyle='normal';
    app.UIFigure.WindowState='normal';


        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)
                        addpath('D:\year3\biometrics\Project_final\Feature_extraction\');
              addpath('D:\year3\biometrics\Project_final\matching\');
                addpath('D:\year3\biometrics\Project_final\models\');
                  addpath('D:\year3\biometrics\Project_final\performance\');
                      addpath('D:\year3\biometrics\Project_final\scenario\');
            global tech_evaluation_image;
            global choosen_model_button;
            if strcmp (choosen_model_button,'KNN')
                image = imread(tech_evaluation_image);
                greyimg = im2gray(image);

                result = detect_class_knn(greyimg);
                
                app.modewindow_Label.Text = num2str(result);
                
            elseif strcmp (choosen_model_button,'Deep Learning')
                [classname,probability] = test_deep_all(tech_evaluation_image);
                
                app.modewindow_Label.Text = classname;
                app.modewindow_Label_2.Text = num2str(probability);

            
            end
        end

        % Button pushed function: viewperformanceButton
        function viewperformanceButtonPushed(app, event)

                        addpath('D:\year3\biometrics\Project_final\Feature_extraction\');
              addpath('D:\year3\biometrics\Project_final\matching\');
                addpath('D:\year3\biometrics\Project_final\models\');
                  addpath('D:\year3\biometrics\Project_final\performance\');
  

            global choosen_model_button;
            global  accuracy
        global EER_number
        global D_prime 
        global Rank_1
        global TMR_at_FMR_1 
        global TMR_at_FMR_001
            
        % images
        global gen_imp
        global roc
        global EER_image
            app.ModelAccuracyLabel.Text = [app.ModelAccuracyLabel.Text,num2str(accuracy)];
            app.EERLabel.Text = [app.EERLabel.Text,num2str(EER_number)];
            app.DPrimeLabel.Text = [app.DPrimeLabel.Text,num2str(D_prime)];
            app.Rank1Label.Text = [app.Rank1Label.Text,num2str(Rank_1)];
            app.TMRatFMR1Label.Text = [app.TMRatFMR1Label.Text,num2str(TMR_at_FMR_1)];
            app.TMRatFMR001Label.Text = [app.TMRatFMR001Label.Text,num2str(TMR_at_FMR_001)];
            
            if ndims(gen_imp) == 3 && size(gen_imp, 3) == 3
                tempFileName = [tempname, '.png']; % Create a temporary file name
                imwrite(gen_imp, tempFileName);  % Write image data to a temporary file
                app.genimp_image.ImageSource = tempFileName; % Set the image source to the temporary file
            else
                disp('Invalid image data');
            end


             if ndims(roc) == 3 && size(roc, 3) == 3
                tempFileName = [tempname, '.png']; % Create a temporary file name
                imwrite(roc, tempFileName);  % Write image data to a temporary file
                app.roc_image.ImageSource = tempFileName; % Set the image source to the temporary file
            else
                disp('Invalid image data');
             end


              if ndims(EER_image) == 3 && size(EER_image, 3) == 3
                tempFileName = [tempname, '.png']; % Create a temporary file name
                imwrite(EER_image, tempFileName);  % Write image data to a temporary file
                app.eer_image.ImageSource = tempFileName; % Set the image source to the temporary file
            else
                disp('Invalid image data');
            end

            uistack(app.model_performance,"top");


        end

        % Button pushed function: EnrollmentButton
        function EnrollmentButtonPushed(app, event)
            uistack(app.credentialswindow,"top");
            app.IncorrectPasswordLabel.Visible='off';

            app.UsernameEditField.Value = "";
            app.PasswordEditField.Value = "";

            
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            
            
            global usernamefield
            global passwordfield

            app.PathLabel.Text = "Path : ";
            
                        app.enrollment_image.ImageSource = "";
                        app.enrollment_EditField_name.Value = "";
                        app.enrollment_EditField_label.Value = 0;
            username = 'admin';
            password = 'admin';


            if strcmp(username,usernamefield) && strcmp(password,passwordfield)
                        uistack(app.enrollmentwindow,"top");
                        app.YoureSuccessfullyEnrolledLabel.Visible = 'off';
                        app.scenarioHomeButton.Visible='off';
                        
            else
                app.IncorrectPasswordLabel.Visible='on';
                drawnow; % Update the UI immediately


                pause(2);

                app.IncorrectPasswordLabel.Visible='off';


            end
            
        end

        % Value changing function: UsernameEditField
        function UsernameEditFieldValueChanging(app, event)
            global usernamefield
            usernamefield = event.Value;
            
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            global passwordfield
            passwordfield = event.Value;
            
        end

        % Button pushed function: enrollment_browse
        function enrollment_browseButtonPushed(app, event)
            global enroll_image          
            % Open file dialog for image selection
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select Image File');
    
    % Check if the user canceled file selection
    if isequal(file, 0)
        return; % User canceled, do nothing
    end
    
    % Construct full file path
    enroll_image = fullfile(path, file);
    
    
    % Display the image in the Image component
    app.enrollment_image.ImageSource = enroll_image;
    app.UIFigure.WindowStyle='normal';
    app.UIFigure.WindowState='normal';

        end

        % Button pushed function: EnrollButton
        function EnrollButtonPushed(app, event)

                        addpath('D:\year3\biometrics\Project_final\Feature_extraction\');
              addpath('D:\year3\biometrics\Project_final\matching\');
                addpath('D:\year3\biometrics\Project_final\models\');
                  addpath('D:\year3\biometrics\Project_final\performance\');
                    addpath('D:\year3\biometrics\Project_final\scenario\');
            global enrollment_editfield_label;
            global enrollment_editfield_name;
            global enroll_image;
            app.YoureSuccessfullyEnrolledLabel.Visible = 'on';
            app.scenarioHomeButton.Visible='on';
            test_enroll(enrollment_editfield_label,enroll_image,enrollment_editfield_name);
        end

        % Button pushed function: scenarioHomeButton
        function scenarioHomeButtonPushed(app, event)
                        uistack(app.scenarioevaluation,"top");
                        app.scenario_Image.ImageSource = "";
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
                        addpath('D:\year3\biometrics\Project_final\Feature_extraction\');
              addpath('D:\year3\biometrics\Project_final\matching\');
                addpath('D:\year3\biometrics\Project_final\models\');
                  addpath('D:\year3\biometrics\Project_final\performance\');
                    addpath('D:\year3\biometrics\Project_final\scenario\');


           global scenario_image
           global name
           global probability
           global not


            [name_,probability_] = compare_test(scenario_image);
            app.ProbabilityLabel.Text = "";
            app.NameLabel.Text = "";
            name = name_{1};
            probability = probability_;
            
            not = name_{1};

            if strcmp(not,"not")
                app.NotastudentLabel.Visible='on';
                drawnow;

                pause(5);
                app.NotastudentLabel.Visible='off';
               

            else
                                app.NameLabel.Visible="on";
                                app.ProbabilityLabel.Visible="on";
                                                drawnow;

                app.NameLabel.Text = "Name:  ";
                app.ProbabilityLabel.Text = "Probability:  ";

   
                drawnow;

                 app.NameLabel.Text = [app.NameLabel.Text,name];
                app.ProbabilityLabel.Text = [app.ProbabilityLabel.Text,num2str(probability)];
             
            end
            
        end

        % Button pushed function: back3
        function back3ButtonPushed(app, event)

                        addpath('D:\year3\biometrics\Project_final\Feature_extraction\');
              addpath('D:\year3\biometrics\Project_final\matching\');
                addpath('D:\year3\biometrics\Project_final\models\');
                  addpath('D:\year3\biometrics\Project_final\performance\');
                    addpath('D:\year3\biometrics\Project_final\scenario\');

            global tech_evaluation_image
            uistack(app.technologyevaluation,"top");
                        app.modewindow_Label.Text = "";
                        app.modewindow_Label_2.Text = "";
                        app.model_image.ImageSource = "";
                        
        end

        % Button pushed function: back4
        function back4ButtonPushed(app, event)
                        uistack(app.modelwindow,"top");
                        app.TMRatFMR001Label.Text = "TMR at FMR = 0.01% : ";
                        app.TMRatFMR1Label.Text="TMR at FMR = 1% : ";
                        app.DPrimeLabel.Text="D prime : ";
                        app.EERLabel.Text="EER : ";
                        % app.EERCurveLabel.Text="EER Curve: ";
                       % app.ROCCurveLabel.Text="ROC Curve: ";
                       % app.GenImpCurveLabel.Text= "GenImp Curve ";
                        app.Rank1Label.Text ="Rank 1: ";
                        app.ModelAccuracyLabel.Text="Model Accuracy : ";

        end

        % Button pushed function: back5
        function back5ButtonPushed(app, event)
                        uistack(app.scenarioevaluation,"top");
                        app.scenario_Image.ImageSource = "";
        end

        % Button pushed function: back6
        function back6ButtonPushed(app, event)

                        addpath('D:\year3\biometrics\Project_final\Feature_extraction\');
              addpath('D:\year3\biometrics\Project_final\matching\');
                addpath('D:\year3\biometrics\Project_final\models\');
                  addpath('D:\year3\biometrics\Project_final\performance\');
                    addpath('D:\year3\biometrics\Project_final\scenario\');

                        uistack(app.scenarioevaluation,"top");
                        app.PasswordEditField.Value = "";
                        app.UsernameEditField.Value = "";

                                    app.PathLabel.Text = "Path : ";

                        app.scenario_Image.ImageSource = "";
                        app.enrollment_image.ImageSource = "";
                        app.enrollment_EditField_name.Value = "";
                        app.enrollment_EditField_label.Value = 0;

                        app.NotastudentLabel.Visible = "off";

        end

        % Callback function
        function enrollment_EditField_labelValueChanging(app, event)

        end

        % Value changing function: enrollment_EditField_name
        function enrollment_EditField_nameValueChanging(app, event)
            global enrollment_editfield_name
            enrollment_editfield_name = event.Value;
            
        end

        % Value changed function: enrollment_EditField_label
        function enrollment_EditField_labelValueChanged(app, event)
                       global enrollment_editfield_label;
                       enrollment_editfield_label = app.enrollment_EditField_label.Value;

        end

        % Button pushed function: back_button
        function back_buttonButtonPushed(app, event)
            uistack(app.technologyevaluation,"top");
            app.decisiontree_model_image.ImageSource = "";
            app.Label_name.Text = "IRIS :  ";
        end

        % Button pushed function: decisiontree_model_browse
        function decisiontree_model_browseButtonPushed(app, event)
            global decisiontree_image          
            % Open file dialog for image selection
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select Image File');
    
    % Check if the user canceled file selection
    if isequal(file, 0)
        return; % User canceled, do nothing
    end
    
    % Construct full file path
    decisiontree_image = fullfile(path, file);
    
    
    % Display the image in the Image component
    app.decisiontree_model_image.ImageSource = decisiontree_image;
    app.UIFigure.WindowStyle='normal';
    app.UIFigure.WindowState='normal';


        end

        % Button pushed function: decisiontree_submitButton
        function decisiontree_submitButtonPushed(app, event)
            global choosen_model_button
            global decisiontree_image

            app.Label_name.Text = "IRIS :  ";
            

            if strcmp(choosen_model_button , 'Decision Tree')
                image = imread(decisiontree_image);
                greyimg = im2gray(image);
                result = detect_class_trees(greyimg);
                app.Label_name.Text =  [app.Label_name.Text,num2str(result)];
                end
        end

        % Button pushed function: clickButton
        function clickButtonPushed(app, event)
            uistack(app.credentialswindow,"top");
            app.IncorrectPasswordLabel.Visible = "off";
        end

        % Button pushed function: SubmitDataset
        function SubmitDatasetButtonPushed(app, event)
            global folderName
            folderName = uigetdir;
        
        % Check if user selected a folder or canceled the dialog
        if folderName ~= 0
            % Update the text box with the selected folder path
            app.PathLabel.Text = [folderName,"\"];
        else
            % Clear the text box if no folder was selected
            app.PathLabel.Text = '';
        end

        end

        % Button pushed function: SubmitDataset_2
        function SubmitDataset_2ButtonPushed(app, event)
            global folderName
            admin_panel(folderName,pwd);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.scenarioevaluation = uipanel(app.UIFigure);
            app.WelcometoAinShamsAttendanceSystemLabel = uilabel(app.scenarioevaluation);
            app.WelcometoAinShamsAttendanceSystemLabel.Text = 'Welcome to Ain Shams Attendance System';
            app.InsertYourPhotoLabel = uilabel(app.scenarioevaluation);
            app.InsertYourPhotoLabel.Text = 'Insert Your Photo :';
            app.scenario_Browse = uibutton(app.scenarioevaluation, 'push');
            app.scenario_Browse.ButtonPushedFcn = createCallbackFcn(app, @scenario_BrowsePushed, true);
            app.scenario_Browse.Text = 'Browse';
            app.scenario_Image = uiimage(app.scenarioevaluation);
            app.back2 = uibutton(app.scenarioevaluation, 'push');
            app.back2.ButtonPushedFcn = createCallbackFcn(app, @back2ButtonPushed, true);
            app.back2.Icon = 'back.png';
            app.back2.Text = '';
            app.EnrollmentButton = uibutton(app.scenarioevaluation, 'push');
            app.EnrollmentButton.ButtonPushedFcn = createCallbackFcn(app, @EnrollmentButtonPushed, true);
            app.EnrollmentButton.Text = 'Enrollment';
            app.SubmitButton = uibutton(app.scenarioevaluation, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.NameLabel = uilabel(app.scenarioevaluation);
            app.NameLabel.Text = 'Name :  ';
            app.ProbabilityLabel = uilabel(app.scenarioevaluation);
            app.ProbabilityLabel.Text = 'Probability :  ';
            app.NotastudentLabel = uilabel(app.scenarioevaluation);
            app.NotastudentLabel.Text = 'Not a student';
            app.modelwindow = uipanel(app.UIFigure);
            app.model_label = uilabel(app.modelwindow);
            app.model_label.Text = 'Insert Your Photo :';
            app.model_browse = uibutton(app.modelwindow, 'push');
            app.model_browse.ButtonPushedFcn = createCallbackFcn(app, @model_browseButtonPushed, true);
            app.model_browse.Text = 'Browse';
            app.model_image = uiimage(app.modelwindow);
            app.viewperformanceButton = uibutton(app.modelwindow, 'push');
            app.viewperformanceButton.ButtonPushedFcn = createCallbackFcn(app, @viewperformanceButtonPushed, true);
            app.viewperformanceButton.Text = 'view performance';
            app.submitButton = uibutton(app.modelwindow, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            app.modewindow_Label = uilabel(app.modelwindow);
            app.modewindow_Label.Text = '';
            app.back3 = uibutton(app.modelwindow, 'push');
            app.back3.ButtonPushedFcn = createCallbackFcn(app, @back3ButtonPushed, true);
            app.back3.Icon = 'back.png';
            app.back3.Text = '';
            app.modewindow_Label_2 = uilabel(app.modelwindow);
            app.modewindow_Label_2.Text = '';
            app.technologyevaluation = uipanel(app.UIFigure);
            app.DeepLearningButton = uibutton(app.technologyevaluation, 'push');
            app.DeepLearningButton.ButtonPushedFcn = createCallbackFcn(app, @DeepLearningButtonPushed, true);
            app.DeepLearningButton.Text = 'Deep Learning';
            app.KNNButton = uibutton(app.technologyevaluation, 'push');
            app.KNNButton.ButtonPushedFcn = createCallbackFcn(app, @KNNButtonPushed, true);
            app.KNNButton.Text = 'KNN';
            app.ChooseAlgorithmLabel = uilabel(app.technologyevaluation);
            app.ChooseAlgorithmLabel.Text = 'Choose Algorithm';
            app.DecisionTreeButton = uibutton(app.technologyevaluation, 'push');
            app.DecisionTreeButton.ButtonPushedFcn = createCallbackFcn(app, @DecisionTreeButtonPushed, true);
            app.DecisionTreeButton.Text = 'Decision Tree';
            app.back1 = uibutton(app.technologyevaluation, 'push');
            app.back1.ButtonPushedFcn = createCallbackFcn(app, @back1Pushed, true);
            app.back1.Icon = 'back.png';
            app.back1.Text = '';
            app.model_performance = uipanel(app.UIFigure);
            app.ModelAccuracyLabel = uilabel(app.model_performance);
            app.ModelAccuracyLabel.Text = 'Model Accuracy :';
            app.genimp_image = uiimage(app.model_performance);
            app.eer_image = uiimage(app.model_performance);
            app.roc_image = uiimage(app.model_performance);
            app.GenImpCurveLabel = uilabel(app.model_performance);
            app.GenImpCurveLabel.Text = 'GenImp Curve';
            app.ROCCurveLabel = uilabel(app.model_performance);
            app.ROCCurveLabel.Text = 'ROC Curve';
            app.EERCurveLabel = uilabel(app.model_performance);
            app.EERCurveLabel.Text = 'EER Curve';
            app.EERLabel = uilabel(app.model_performance);
            app.EERLabel.Text = 'EER :';
            app.DPrimeLabel = uilabel(app.model_performance);
            app.DPrimeLabel.Text = 'D Prime :';
            app.Rank1Label = uilabel(app.model_performance);
            app.Rank1Label.Text = 'Rank 1 :';
            app.TMRatFMR1Label = uilabel(app.model_performance);
            app.TMRatFMR1Label.Text = 'TMR at FMR = 1% :';
            app.TMRatFMR001Label = uilabel(app.model_performance);
            app.TMRatFMR001Label.Text = 'TMR at FMR = 0.01% :';
            app.back4 = uibutton(app.model_performance, 'push');
            app.back4.ButtonPushedFcn = createCallbackFcn(app, @back4ButtonPushed, true);
            app.back4.Icon = 'back.png';
            app.back4.Text = '';
            app.decisiontreewindow = uipanel(app.UIFigure);
            app.model_label_2 = uilabel(app.decisiontreewindow);
            app.model_label_2.Text = 'Insert Your Photo :';
            app.decisiontree_model_browse = uibutton(app.decisiontreewindow, 'push');
            app.decisiontree_model_browse.ButtonPushedFcn = createCallbackFcn(app, @decisiontree_model_browseButtonPushed, true);
            app.decisiontree_model_browse.Text = 'Browse';
            app.decisiontree_model_image = uiimage(app.decisiontreewindow);
            app.decisiontree_submitButton = uibutton(app.decisiontreewindow, 'push');
            app.decisiontree_submitButton.ButtonPushedFcn = createCallbackFcn(app, @decisiontree_submitButtonPushed, true);
            app.decisiontree_submitButton.Text = 'submit';
            app.Label_name = uilabel(app.decisiontreewindow);
            app.Label_name.Text = 'IRIS :  ';
            app.back_button = uibutton(app.decisiontreewindow, 'push');
            app.back_button.ButtonPushedFcn = createCallbackFcn(app, @back_buttonButtonPushed, true);
            app.back_button.Icon = 'back.png';
            app.back_button.Text = '';
            app.decisiontree_accuracy = uilabel(app.decisiontreewindow);
            app.decisiontree_accuracy.Text = 'Accuracy : ';
            app.credentialswindow = uipanel(app.UIFigure);
            app.loginpanel = uipanel(app.credentialswindow);
            app.EnterCredentialsLabel = uilabel(app.loginpanel);
            app.EnterCredentialsLabel.Text = 'Enter Credentials :';
            app.UsernameEditFieldLabel = uilabel(app.loginpanel);
            app.UsernameEditFieldLabel.Text = 'Username :';
            app.UsernameEditField = uieditfield(app.loginpanel, 'text');
            app.UsernameEditField.ValueChangingFcn = createCallbackFcn(app, @UsernameEditFieldValueChanging, true);
            app.PasswordEditFieldLabel = uilabel(app.loginpanel);
            app.PasswordEditFieldLabel.Text = 'Password :';
            app.PasswordEditField = uieditfield(app.loginpanel, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.loginpanel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.IncorrectPasswordLabel = uilabel(app.loginpanel);
            app.IncorrectPasswordLabel.Text = 'Incorrect Password';
            app.back5 = uibutton(app.credentialswindow, 'push');
            app.back5.ButtonPushedFcn = createCallbackFcn(app, @back5ButtonPushed, true);
            app.back5.Icon = 'back.png';
            app.back5.Text = '';
            app.home = uipanel(app.UIFigure);
            app.IRISDetectionProjectLabel = uilabel(app.home);
            app.IRISDetectionProjectLabel.Text = 'IRIS Detection Project';
            app.TechnologyEvaluationButton = uibutton(app.home, 'push');
            app.TechnologyEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @TechnologyEvaluationButtonPushed, true);
            app.TechnologyEvaluationButton.Text = 'Technology Evaluation';
            app.ScenarioEvaluationButton = uibutton(app.home, 'push');
            app.ScenarioEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @ScenarioEvaluationButtonPushed, true);
            app.ScenarioEvaluationButton.Text = 'Scenario Evaluation';
            app.clickButton = uibutton(app.home, 'push');
            app.clickButton.ButtonPushedFcn = createCallbackFcn(app, @clickButtonPushed, true);
            app.clickButton.Text = 'click';
            app.EnterifyoureadminLabel = uilabel(app.home);
            app.EnterifyoureadminLabel.Text = 'Enter if you''re admin';
            app.enrollmentwindow = uipanel(app.UIFigure);
            app.enrollment_image = uiimage(app.enrollmentwindow);
            app.enrollment_browse = uibutton(app.enrollmentwindow, 'push');
            app.enrollment_browse.ButtonPushedFcn = createCallbackFcn(app, @enrollment_browseButtonPushed, true);
            app.enrollment_browse.Text = 'Browse';
            app.EnrollButton = uibutton(app.enrollmentwindow, 'push');
            app.EnrollButton.ButtonPushedFcn = createCallbackFcn(app, @EnrollButtonPushed, true);
            app.EnrollButton.Text = 'Enroll';
            app.enrollment_label = uilabel(app.enrollmentwindow);
            app.enrollment_label.Text = 'Insert Your Photo :';
            app.YoureSuccessfullyEnrolledLabel = uilabel(app.enrollmentwindow);
            app.YoureSuccessfullyEnrolledLabel.Text = 'You''re Successfully Enrolled';
            app.scenarioHomeButton = uibutton(app.enrollmentwindow, 'push');
            app.scenarioHomeButton.ButtonPushedFcn = createCallbackFcn(app, @scenarioHomeButtonPushed, true);
            app.scenarioHomeButton.Text = 'Home';
            app.back6 = uibutton(app.enrollmentwindow, 'push');
            app.back6.ButtonPushedFcn = createCallbackFcn(app, @back6ButtonPushed, true);
            app.back6.Icon = 'back.png';
            app.back6.Text = '';
            app.EditFieldLabel_2 = uilabel(app.enrollmentwindow);
            app.EditFieldLabel_2.Text = 'Edit Field';
            app.enrollment_EditField_name = uieditfield(app.enrollmentwindow, 'text');
            app.enrollment_EditField_name.ValueChangingFcn = createCallbackFcn(app, @enrollment_EditField_nameValueChanging, true);
            app.NameLabel_2 = uilabel(app.enrollmentwindow);
            app.NameLabel_2.Text = 'Name : ';
            app.Label = uilabel(app.enrollmentwindow);
            app.Label.Text = 'Label :';
            app.EditField2Label = uilabel(app.enrollmentwindow);
            app.EditField2Label.Text = 'Edit Field2';
            app.enrollment_EditField_label = uieditfield(app.enrollmentwindow, 'numeric');
            app.enrollment_EditField_label.ValueChangedFcn = createCallbackFcn(app, @enrollment_EditField_labelValueChanged, true);
            app.SubmitDataset = uibutton(app.enrollmentwindow, 'push');
            app.SubmitDataset.ButtonPushedFcn = createCallbackFcn(app, @SubmitDatasetButtonPushed, true);
            app.SubmitDataset.Text = 'Browse';
            app.EnterNewDataSetLabel = uilabel(app.enrollmentwindow);
            app.EnterNewDataSetLabel.Text = 'Enter New DataSet  :';
            app.PathLabel = uilabel(app.enrollmentwindow);
            app.PathLabel.Text = 'Path :';
            app.SubmitDataset_2 = uibutton(app.enrollmentwindow, 'push');
            app.SubmitDataset_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitDataset_2ButtonPushed, true);
            app.SubmitDataset_2.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end